classdef ImageProcessingAnalysisSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TitleLabelUIFigure  matlab.ui.Figure
        GrayscaleButton     matlab.ui.control.Button
        LoadImageButton     matlab.ui.control.Button
        IMAGEPROCESSINGANALYSISSYSTEMLabel  matlab.ui.control.Label
        ProcessedAxes       matlab.ui.control.UIAxes
        OriginalAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        I
      end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
    [file, path] = uigetfile( ...
        {'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files'}, ...
        'Select an Image');

    if isequal(file, 0)
        return;
    end

    app.I = imread(fullfile(path, file));

    imshow(app.I, 'Parent', app.OriginalAxes);

    title(app.OriginalAxes, 'Original Image');
        end

        % Button down function: ProcessedAxes
        function ProcessedAxesButtonDown(app, event)
            
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
           if isempty(app.I)
    uialert(app.UIFigure, ...
        'Please load an image first.', ...
        'No Image');
    return;
end

if size(app.I, 3) == 3
    grayImage = rgb2gray(app.I);
else
    grayImage = app.I;
end

imshow(grayImage, 'Parent', app.ProcessedAxes);

title(app.ProcessedAxes, 'Grayscale Image'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TitleLabelUIFigure = uifigure('Visible', 'off');
            app.TitleLabelUIFigure.Name = 'TitleLabel';
            app.OriginalAxes = uiaxes(app.TitleLabelUIFigure);
            title(app.OriginalAxes, 'OriginalAxes')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.ProcessedAxes = uiaxes(app.TitleLabelUIFigure);
            title(app.ProcessedAxes, 'ProcessedAxes')
            xlabel(app.ProcessedAxes, 'X')
            ylabel(app.ProcessedAxes, 'Y')
            zlabel(app.ProcessedAxes, 'Z')
            app.ProcessedAxes.ButtonDownFcn = createCallbackFcn(app, @ProcessedAxesButtonDown, true);
            app.IMAGEPROCESSINGANALYSISSYSTEMLabel = uilabel(app.TitleLabelUIFigure);
            app.IMAGEPROCESSINGANALYSISSYSTEMLabel.Tag = 'TitleLabel';
            app.IMAGEPROCESSINGANALYSISSYSTEMLabel.Text = 'IMAGE PROCESSING & ANALYSIS SYSTEM';
            app.LoadImageButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'LoadImageButton';
            app.GrayscaleButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            % Show the figure after all components are created
            app.TitleLabelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingAnalysisSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TitleLabelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TitleLabelUIFigure)
        end
    end
end